classdef zakir < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        RecordAudio6sButton  matlab.ui.control.Button
        FilterPlotButton     matlab.ui.control.Button
        CutoffHzLabel        matlab.ui.control.Label
        CutoffSlider         matlab.ui.control.Slider
        CutoffSliderLabel    matlab.ui.control.Label
        SaveButton           matlab.ui.control.Button
        PlayFilteredButton   matlab.ui.control.Button
        PlayOriginalButton   matlab.ui.control.Button
        LoadButtonwav        matlab.ui.control.Button
        UIAxes_6             matlab.ui.control.UIAxes
        UIAxes_5             matlab.ui.control.UIAxes
        UIAxes_4             matlab.ui.control.UIAxes
        UIAxes_3             matlab.ui.control.UIAxes
        UIAxes_2             matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
                fs double
                audio double
                filtered double

    end
    
    methods (Access = private)
        function updateFilterAndPlots(app)
                                % ====== CHECK AUDIO LOADED ======
                                if isempty(app.audio)
                                    return;
                                end
                            
                                % ====== GET SLIDER VALUE ======
                                cutoff = app.CutoffSlider.Value;   % Hz
                                fs = app.fs;                       % Sampling frequency
                                N = 101;                           % Filter length
                            
                                % ====== ENSURE MINIMUM POSITIVE CUTOFF ======
                                if cutoff <= 0
                                    cutoff = 0.001; % minimum cutoff to prevent fir1 error
                                end
                            
                                % ====== DESIGN LOW-PASS FIR FILTER ======
                                h = fir1(N-1, cutoff/(fs/2));
                            
                                % ====== APPLY FILTER ======
                                y = conv(app.audio, h, 'same');
                            
                                % ====== STORE RESULT ======
                                app.filtered = y;
                            
                                % ====== COMPUTE SPECTRA ======
                                X = fft(app.audio);
                                Y = fft(y);
                                H = fft(h, length(X));
                                f = linspace(0, fs/2, floor(length(X)/2));
                            
                                % ====== PLOT RESULTS ======
                                plot(app.UIAxes, (0:length(app.audio)-1)/fs, app.audio);
                                title(app.UIAxes, 'Input Signal (Time)');
                                xlabel(app.UIAxes, 'Time (s)');
                                ylabel(app.UIAxes, 'Amplitude');
                            
                                plot(app.UIAxes_2, h);
                                title(app.UIAxes_2, 'Filter Impulse Response');
                                xlabel(app.UIAxes_2, 'Samples');
                                ylabel(app.UIAxes_2, 'h[n]');
                            
                                plot(app.UIAxes_3, f, abs(X(1:floor(end/2))));
                                title(app.UIAxes_3, 'Input Spectrum');
                                xlabel(app.UIAxes_3, 'Frequency (Hz)');
                                ylabel(app.UIAxes_3, '|X(f)|');
                            
                                plot(app.UIAxes_4, f, abs(H(1:floor(end/2))));
                                title(app.UIAxes_4, 'Filter Spectrum');
                                xlabel(app.UIAxes_4, 'Frequency (Hz)');
                                ylabel(app.UIAxes_4, '|H(f)|');
                            
                                plot(app.UIAxes_5, (0:length(y)-1)/fs, y);
                                title(app.UIAxes_5, 'Filtered Signal (Time)');
                                xlabel(app.UIAxes_5, 'Time (s)');
                                ylabel(app.UIAxes_5, 'Amplitude');
                            
                                plot(app.UIAxes_6, f, abs(Y(1:floor(end/2))));
                                title(app.UIAxes_6, 'Filtered Spectrum');
                                xlabel(app.UIAxes_6, 'Frequency (Hz)');
                                ylabel(app.UIAxes_6, '|Y(f)|');
                            end
        
            end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButtonwav
        function LoadButtonwavPushed(app, event)
                    
                    [file, path] = uigetfile('*.wav', 'Select an audio file');
                    if isequal(file, 0)
                        return;
                    end
                    [app.audio, app.fs] = audioread(fullfile(path, file));
                    app.audio = mean(app.audio, 2); % Convert to mono if stereo
                    app.audio = app.audio(1:min(length(app.audio), 6*app.fs)); % Limit to 6 seconds
                    t = (0:length(app.audio)-1)/app.fs;
                    plot(app.UIAxes, t, app.audio);
                    title(app.UIAxes, 'Input Signal (Time)');
                    xlabel(app.UIAxes, 'Time (s)');
                    ylabel(app.UIAxes, 'Amplitude');
        end

        % Button pushed function: RecordAudio6sButton
        function RecordAudio6sButtonPushed(app, event)
            
                         app.fs = 44100;                      % Sampling frequency
                        recObj = audiorecorder(app.fs, 16, 1);
                          recordblocking(recObj, 6);           % Record 6 seconds
                        app.audio = getaudiodata(recObj);    % ✅ Works now
                    
                        % Optional: plot the waveform
                        plot(app.UIAxes, (0:length(app.audio)-1)/app.fs, app.audio);
                        title(app.UIAxes, 'Recorded Audio');
                        xlabel(app.UIAxes, 'Time (s)');
                        ylabel(app.UIAxes, 'Amplitude');
        end

        % Button pushed function: FilterPlotButton
        function FilterPlotButtonPushed(app, event)
                      
                  app.updateFilterAndPlots();
       
        end

        % Button pushed function: PlayOriginalButton
        function PlayOriginalButtonPushed(app, event)
                  if isempty(app.audio)
                    uialert(app.UIFigure, 'No audio loaded.', 'Error');
                    return;
                end
                clear sound;            % Stop any sound currently playing
                sound(app.audio, app.fs);
        end

        % Button pushed function: PlayFilteredButton
        function PlayFilteredButtonPushed(app, event)
       if isempty(app.filtered)
                    uialert(app.UIFigure, 'Filter the signal first!', 'Error');
                    return;
                end
                clear sound;            % Stop any sound currently playing
                sound(app.filtered, app.fs);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
             fs = 44100;       
               audio = randn(1, fs);  
             filtered = lowpass(audio, 4000, fs); 
                          if isempty(app.filtered)
                            uialert(app.UIFigure, 'No filtered audio to save!', 'Error');
                            return;
                        end
                        [file, path] = uiputfile('filtered_output.wav', 'Save Filtered Audio');
                        if isequal(file, 0)
                            return;
                        end
                        audiowrite(fullfile(path, file), app.filtered, app.fs);
                        uialert(app.UIFigure, 'Filtered audio saved successfully!', 'Success');                    
        end

        % Value changed function: CutoffSlider
        function CutoffSliderValueChanged(app, event)
                        value = app.CutoffSlider.Value;   
                app.CutoffHzLabel.Text = sprintf('%.0f Hz', value);   % Update label text live
               app.updateFilterAndPlots();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_6, 'Z')
            app.LoadButtonwav = uibutton(app.UIFigure, 'push');
            app.LoadButtonwav.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonwavPushed, true);
            app.LoadButtonwav.Text = 'LoadButton (.wav)';
            app.PlayOriginalButton = uibutton(app.UIFigure, 'push');
            app.PlayOriginalButton.ButtonPushedFcn = createCallbackFcn(app, @PlayOriginalButtonPushed, true);
            app.PlayOriginalButton.Text = 'PlayOriginalButton';
            app.PlayFilteredButton = uibutton(app.UIFigure, 'push');
            app.PlayFilteredButton.ButtonPushedFcn = createCallbackFcn(app, @PlayFilteredButtonPushed, true);
            app.PlayFilteredButton.Text = 'PlayFilteredButton';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'SaveButton';
            app.CutoffSliderLabel = uilabel(app.UIFigure);
            app.CutoffSliderLabel.Text = 'CutoffSlider';
            app.CutoffSlider = uislider(app.UIFigure);
            app.CutoffSlider.Limits = [0 4000];
            app.CutoffSlider.ValueChangedFcn = createCallbackFcn(app, @CutoffSliderValueChanged, true);
            app.CutoffHzLabel = uilabel(app.UIFigure);
            app.CutoffHzLabel.Text = 'Cutoff (Hz)';
            app.FilterPlotButton = uibutton(app.UIFigure, 'push');
            app.FilterPlotButton.ButtonPushedFcn = createCallbackFcn(app, @FilterPlotButtonPushed, true);
            app.FilterPlotButton.Text = 'Filter & Plot';
            app.RecordAudio6sButton = uibutton(app.UIFigure, 'push');
            app.RecordAudio6sButton.ButtonPushedFcn = createCallbackFcn(app, @RecordAudio6sButtonPushed, true);
            app.RecordAudio6sButton.Text = 'Record Audio (6s)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = zakir

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end